classdef RouthStability < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DOWNLOADGRAPHButton             matlab.ui.control.Button
        RootPlotExplanationTextArea     matlab.ui.control.TextArea
        RootPlotExplanationTextAreaLabel  matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        DOWNLOADTXTFILEButton           matlab.ui.control.Button
        LatexPolyLabel                  matlab.ui.control.Label
        StabilityResultOutputTextArea   matlab.ui.control.TextArea
        StabilityResultOutputTextAreaLabel  matlab.ui.control.Label
        RouthTableOutputTextArea        matlab.ui.control.TextArea
        RouthTableOutputTextAreaLabel   matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        DegreeInputFieldEditField       matlab.ui.control.EditField
        DegreeInputFieldEditFieldLabel  matlab.ui.control.Label
        EnterDegreeofPolynomialLabel    matlab.ui.control.Label
        NumericInputFieldEditField      matlab.ui.control.EditField
        NumericInputFieldEditFieldLabel  matlab.ui.control.Label
        EnterCoefficientseg14641Label   matlab.ui.control.Label
        SelectModeButtonGroup           matlab.ui.container.ButtonGroup
        SymbolicAnalysisofRootsButton   matlab.ui.control.RadioButton
        NumericalAnanlysisofRootsButton  matlab.ui.control.RadioButton
        RouthHourwitzStabilityCriterionLabel  matlab.ui.control.Label
        RootPlotAxes                    matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
        
            selectedBtn = app.SelectModeButtonGroup.SelectedObject;

            if selectedBtn == app.NumericalAnanlysisofRootsButton
                % === NUMERICAL ANALYSIS MODE ===
                try
    inputStr = strtrim(app.NumericInputFieldEditField.Value);
    inputStr = regexprep(inputStr, '[\[\]]', '');  % remove brackets
    coeffs = str2double(strsplit(inputStr));

    if isempty(coeffs) || any(isnan(coeffs))
        uialert(app.UIFigure, 'Please enter valid numeric coefficients, e.g., "1 4 6 4 1"', 'Input Error');
        return;
    end

    [routhTable, stabilityStatement, signChanges] = routhAnalyzer(coeffs);

    % Display polynomial
    syms s
    eqn = poly2sym(coeffs, s);
    latexStr = ['$' latex(eqn) '$'];
    app.LatexPolyLabel.Text = latexStr;

    % Display Routh table
    tableStr = evalc('disp(routhTable)');
    app.RouthTableOutputTextArea.Value = splitlines(string(tableStr));

    % Show number of stable roots
    nRoots = length(coeffs) - 1;
    nStable = nRoots - signChanges;
    rootInfoStr = sprintf('Out of %d roots, %d have negative real parts.', nRoots, nStable);

    % Combine and show result
    app.StabilityResultOutputTextArea.Value = {
        stabilityStatement;
        rootInfoStr
    };

    % === Plot Root Locations ===
    cla(app.RootPlotAxes, 'reset');  % Clear previous plot
    p = roots(coeffs);  % Calculate roots of the polynomial
    scatter(app.RootPlotAxes, real(p), imag(p), 70, 'b', 'filled');  % Blue points for roots
    hold(app.RootPlotAxes, 'on');
    xline(app.RootPlotAxes, 0, '--r', 'LineWidth', 1.5);  % Imaginary axis
    yline(app.RootPlotAxes, 0, '--k', 'LineWidth', 1.0);  % Real axis
    hold(app.RootPlotAxes, 'off');

    title(app.RootPlotAxes, 'Pole Locations in s-plane');
    xlabel(app.RootPlotAxes, 'Re');
    ylabel(app.RootPlotAxes, 'Im');
    grid(app.RootPlotAxes, 'on');
    axis(app.RootPlotAxes, 'equal');  % Keep x and y scales equal

% Optional: Add explanation if you have a note box
if isprop(app, 'RootPlotNote')
    app.RootPlotNote.Value = {
        'Blue dots: roots of the polynomial.';
        'Red dashed line: Imaginary axis (Re = 0).';
        'Stable systems have all roots left of red line.'
    };
end

catch
    uialert(app.UIFigure, 'Error in processing numerical data.', 'Execution Error');
end
                    

            elseif selectedBtn == app.SymbolicAnalysisofRootsButton
                cla(app.RootPlotAxes, 'reset');
                text(app.RootPlotAxes, 0.5, 0.5, 'Root plot only available in Numerical mode', ...
                'HorizontalAlignment', 'center', ...
                'FontSize', 10, ...
                'Color', [0.5 0.5 0.5]);
                % === SYMBOLIC ANALYSIS MODE ===
                try
                    n = str2double(app.DegreeInputFieldEditField.Value);

                    % Create symbolic coefficient vector
                    coeffs = sym([]);
                    for i = 0:n
                        coeffs(end + 1) = sym(sprintf('a%d', n - i));
                    end

                    syms s
                    eqn = poly2sym(coeffs, s);
                    latexStr = ['$' latex(eqn) '$'];
                    app.LatexPolyLabel.Text = latexStr;

                    row1 = coeffs(1:2:end);
                    row2 = coeffs(2:2:end);

                    numRows = n + 1;
                    numCols = ceil((n + 1)/2);
                    routhTable = sym(zeros(numRows, numCols));
                    routhTable(1,1:length(row1)) = row1;
                    routhTable(2,1:length(row2)) = row2;

                    for i = 3:numRows
                        for j = 1:(numCols - 1)
                            A = routhTable(i-2,1);
                            C = routhTable(i-1,1);
                            if C == 0
                                C = sym('epsilon');
                            end
                            if (j+1) > size(routhTable,2)
                                B = 0; D = 0;
                            else
                                B = routhTable(i-2,j+1);
                                D = routhTable(i-1,j+1);
                            end
                            routhTable(i,j) = simplify((B*C - A*D) / C);
                        end
                    end

                    % Extract symbolic conditions
                    firstCol = routhTable(:,1);
                    condStr = arrayfun(@(i) sprintf('Condition %d: %s > 0', i, char(firstCol(i))), ...
                                       1:length(firstCol), 'UniformOutput', false);
                    tableStr = evalc('disp(routhTable)');
                    app.RouthTableOutputTextArea.Value = splitlines(string(tableStr));
                    app.StabilityResultOutputTextArea.Value = condStr;

                catch ME
                    disp("------ ERROR OCCURRED ------");
                    disp(getReport(ME, 'extended'));
                    uialert(app.UIFigure, ME.message, 'Execution Error');
                end

            else
                uialert(app.UIFigure, 'Please select a mode.', 'Mode Missing');
            end

if selectedBtn == app.NumericalAnanlysisofRootsButton
    % After plotting in Numerical case
    app.RootPlotExplanationTextArea.Value = {
    'Root Plot Description:', ...
    '• Red dashed vertical line → Real axis at 0.', ...
    '• Points on the left side (Re < 0) → Stable roots.', ...
    '• Blue dots → Roots (Poles) of the system.', ...
    '• Move cursor over plot or zoom in to observe pole positions more clearly.'};
elseif selectedBtn == app.SymbolicAnalysisofRootsButton
    app.RootPlotExplanationTextArea.Value = {
    'Root Plot is not available for symbolic analysis.', ...
    'Please enter numeric coefficients to visualize poles in s-plane.'};
end

        end

        % Button pushed function: DOWNLOADTXTFILEButton
        function DOWNLOADTXTFILEButtonPushed(app, event)
              try
        % Ask user to choose file
        [file, path] = uiputfile('routh_report.txt', 'Save Routh-Hurwitz Report As');
        if isequal(file,0)
            return; % user cancelled
        end
        filename = fullfile(path, file);
        fid = fopen(filename, 'w');

        fprintf(fid, '--- Routh-Hurwitz Stability Report ---\n\n');

        % Numeric Polynomial Input
        inputStr = strtrim(app.NumericInputFieldEditField.Value);
        coeffs = str2num(inputStr);  %#ok<ST2NM>

        if ~isempty(coeffs) && isnumeric(coeffs)
            % Generate symbolic polynomial
            syms s;
            polyExpr = poly2sym(coeffs, s);
            fprintf(fid, 'Polynomial:\n   ');
            fprintf(fid, '%s\n\n', char(polyExpr));
        else
            fprintf(fid, 'Polynomial: (could not parse input)\n\n');
        end

        % Routh Table
        fprintf(fid, 'Routh Table:\n');
        routhLines = app.RouthTableOutputTextArea.Value;
        for i = 1:length(routhLines)
            fprintf(fid, '   %s\n', routhLines{i});
        end

        % Stability verdict
        fprintf(fid, '\nStability Verdict:\n');
        verdictLines = app.StabilityResultOutputTextArea.Value;
        for i = 1:length(verdictLines)
            fprintf(fid, '   %s\n', verdictLines{i});
        end

        fclose(fid);
        uialert(app.UIFigure, 'Text report exported successfully.', 'Done');

    catch ME
        uialert(app.UIFigure, ['Failed to export report: ' ME.message], 'Error');
              end

              
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
             pdfFile = 'Routh Criteria Explained.pdf';  % Change name if needed
    if isfile(pdfFile)
        open(pdfFile);  % Uses system default PDF viewer
    else
        uialert(app.UIFigure, 'Help file not found. Please ensure RouthHelp.pdf is in the same folder.', 'File Missing');
    end
        end

        % Button pushed function: DOWNLOADGRAPHButton
        function DOWNLOADGRAPHButtonPushed(app, event)
             [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'}, 'Save Plot As');
    
    if isequal(file, 0)
        return;  % User cancelled
    end

    % Create a temporary figure to copy the axes
    tempFig = figure('Visible', 'off');
    ax = copyobj(app.RootPlotAxes, tempFig);
    set(ax, 'Units', 'Normalized', 'Position', [0.13 0.11 0.775 0.815]);

    % Save the figure
    exportgraphics(tempFig, fullfile(path, file));

    close(tempFig);  % Close temp fig
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RootPlotAxes = uiaxes(app.UIFigure);
            title(app.RootPlotAxes, 'Pole Location in S-Plane')
            xlabel(app.RootPlotAxes, 'X')
            ylabel(app.RootPlotAxes, 'Y')
            zlabel(app.RootPlotAxes, 'Z')
            app.RouthHourwitzStabilityCriterionLabel = uilabel(app.UIFigure);
            app.RouthHourwitzStabilityCriterionLabel.Text = 'Routh - Hourwitz Stability Criterion';
            app.SelectModeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectModeButtonGroup.Title = 'Select Mode';
            app.NumericalAnanlysisofRootsButton = uiradiobutton(app.SelectModeButtonGroup);
            app.NumericalAnanlysisofRootsButton.Text = 'Numerical Ananlysis of Roots';
            app.NumericalAnanlysisofRootsButton.Value = true;
            app.SymbolicAnalysisofRootsButton = uiradiobutton(app.SelectModeButtonGroup);
            app.SymbolicAnalysisofRootsButton.Text = 'Symbolic Analysis of Roots';
            app.EnterCoefficientseg14641Label = uilabel(app.UIFigure);
            app.EnterCoefficientseg14641Label.Text = 'Enter Coefficients (e.g., [1 4 6 4 1])';
            app.NumericInputFieldEditFieldLabel = uilabel(app.UIFigure);
            app.NumericInputFieldEditFieldLabel.Text = 'NumericInputField';
            app.NumericInputFieldEditField = uieditfield(app.UIFigure, 'text');
            app.NumericInputFieldEditField.Value = '[1 4 6 4 1]';
            app.EnterDegreeofPolynomialLabel = uilabel(app.UIFigure);
            app.EnterDegreeofPolynomialLabel.Text = {'Enter Degree of Polynomial'; ''; ''};
            app.DegreeInputFieldEditFieldLabel = uilabel(app.UIFigure);
            app.DegreeInputFieldEditFieldLabel.Text = 'DegreeInputField';
            app.DegreeInputFieldEditField = uieditfield(app.UIFigure, 'text');
            app.DegreeInputFieldEditField.Value = '3';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate Routh Table';
            app.RouthTableOutputTextAreaLabel = uilabel(app.UIFigure);
            app.RouthTableOutputTextAreaLabel.Text = 'Routh Table Output';
            app.RouthTableOutputTextArea = uitextarea(app.UIFigure);
            app.StabilityResultOutputTextAreaLabel = uilabel(app.UIFigure);
            app.StabilityResultOutputTextAreaLabel.Text = 'Stability Result Output';
            app.StabilityResultOutputTextArea = uitextarea(app.UIFigure);
            app.LatexPolyLabel = uilabel(app.UIFigure);
            app.LatexPolyLabel.Interpreter = 'latex';
            app.LatexPolyLabel.Text = 'Resultant Polynomial';
            app.DOWNLOADTXTFILEButton = uibutton(app.UIFigure, 'push');
            app.DOWNLOADTXTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNLOADTXTFILEButtonPushed, true);
            app.DOWNLOADTXTFILEButton.Text = 'DOWNLOAD TXT FILE';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RootPlotExplanationTextAreaLabel = uilabel(app.UIFigure);
            app.RootPlotExplanationTextAreaLabel.Text = 'RootPlotExplanationTextArea';
            app.RootPlotExplanationTextArea = uitextarea(app.UIFigure);
            app.RootPlotExplanationTextArea.Tag = 'RootPlotExplanationTextArea';
            app.RootPlotExplanationTextArea.Editable = 'off';
            app.DOWNLOADGRAPHButton = uibutton(app.UIFigure, 'push');
            app.DOWNLOADGRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNLOADGRAPHButtonPushed, true);
            app.DOWNLOADGRAPHButton.Text = 'DOWNLOAD GRAPH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RouthStability

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end